classdef graph_explorer_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CATTGraphExplorerUIFigure  matlab.ui.Figure
        GAxes                      matlab.ui.control.UIAxes
        ColonyDropDownLabel        matlab.ui.control.Label
        ColonyDropDown             matlab.ui.control.DropDown
        MovieDropDownLabel         matlab.ui.control.Label
        MovieDropDown              matlab.ui.control.DropDown
        ImageButton                matlab.ui.control.Button
        ImageallframesButton       matlab.ui.control.Button
        FromLabel                  matlab.ui.control.Label
        FromEditField              matlab.ui.control.NumericEditField
        ToEditFieldLabel           matlab.ui.control.Label
        ToEditField                matlab.ui.control.NumericEditField
        GPlotButton                matlab.ui.control.Button
        FrameDropDownLabel         matlab.ui.control.Label
        FrameDropDown              matlab.ui.control.DropDown
        IDhiglightDropDownLabel    matlab.ui.control.Label
        IDhiglightDropDown         matlab.ui.control.DropDown
        ExportallButton            matlab.ui.control.Button
        IDLabel                    matlab.ui.control.Label
        IDDropDown                 matlab.ui.control.DropDown
        TrackletTextArea           matlab.ui.control.TextArea
        ShowframeButton            matlab.ui.control.Button
        AssignButton               matlab.ui.control.Button
        ZoomButton                 matlab.ui.control.StateButton
        ViewResetButton            matlab.ui.control.Button
        ChooseframesButton         matlab.ui.control.Button
        FramenumberingButtonGroup  matlab.ui.container.ButtonGroup
        experimentButton           matlab.ui.control.RadioButton
        subdirButton               matlab.ui.control.RadioButton
        movieButton                matlab.ui.control.RadioButton
        ImageAxes                  matlab.ui.control.UIAxes
        XYAxes                     matlab.ui.control.UIAxes
        XAxes                      matlab.ui.control.UIAxes
        YAxes                      matlab.ui.control.UIAxes
        YpositionbytimeCheckBox    matlab.ui.control.CheckBox
        LoadButton                 matlab.ui.control.Button
        SingleantCheckBox          matlab.ui.control.CheckBox
        EliminateButton            matlab.ui.control.Button
        ManualfixLabel             matlab.ui.control.Label
        AddexamplesLabel           matlab.ui.control.Label
    end

    
    properties %(Access = private)
        Trck trhandles % Description
        G trgraph
        XY
        GraphPlot
        focus_trj tracklet
        focus_node
        sg
        sg_assigned_ids
        sg_possible_ids
        sg_single_nodes
        sg_src_nodes
        sg_ant_nodes  
        sg_ant_subgraph 
        sg_trjs
        appdata
        dcm_obj
        numbering
        ti
        tf
        lines
        
    end
    
    methods (Access = private)
        
        function setTrck(app,Trck)
            
            app.Trck = Trck;
            
            update_colony_list(app);
            update_graph_list(app);
            update_id_list(app);
            
        end
        
        function update_colony_list(app)
            
            % update colony list
            if app.Trck.prmtrs.geometry_multi_colony
                app.ColonyDropDown.Enable = true;
                app.ColonyDropDown.Items = app.Trck.colony_labels;
                app.ColonyDropDown.Value = app.Trck.colony_labels{1};
            else
                app.ColonyDropDown.Enable = false;
                app.ColonyDropDown.Items = {''};
                app.ColonyDropDown.Value = '';
            end
            
            
        end
        
        function update_graph_list(app)
            
            glist = app.Trck.graphlist;
            glist = arrayfun(@num2str,glist,'UniformOutput',false);
            app.MovieDropDown.Items = glist;
            app.MovieDropDown.Value = glist{1};
            
        end
        
        function update_id_list(app)
            
            app.IDhiglightDropDown.Items = [{'None'};tocol(app.Trck.usedIDs)];
            app.IDhiglightDropDown.Value = 'None';
            
            app.IDDropDown.Items = app.Trck.allLabels;
            app.IDDropDown.Value = app.Trck.usedIDs{1};
            
        end
        
        
        function load_G(app,m,c,refresh)
            
            if nargin<4
                refresh=false;
            end
            
            d = uiprogressdlg(app.CATTGraphExplorerUIFigure,'Title','Loading data','Indeterminate','on');
            
            G = app.Trck.loaddata(m,c);
            setG(app,G,refresh);
            
            close(d);
            
        end
        
        function update_frame_numbers(app)
            
            
            m = app.G.movlist(1);
            
            switch app.numbering
                
                case 'experiment'
                    
                    lims = [app.G.ti.f,app.G.tf.f];
                    from = app.ti.f;
                    to = app.tf.f;
                    
                case 'subdir'
                    
                    sd = m>=[app.Trck.er.subdirs.mi] & m<=[app.Trck.er.subdirs.mf];
                    sdfi = app.Trck.er.movies_info(app.Trck.er.subdirs(sd).mi).fi;
                    lims = [app.G.ti.f,app.G.tf.f] - sdfi + 1;
                    from = app.ti.f - sdfi + 1;
                    to = app.tf.f - sdfi + 1;
                    
                case 'movie'
                    
                    lims = [app.G.ti.mf,app.G.tf.mf];
                    from = app.ti.mf;
                    to = app.tf.mf;
                    
                otherwise
                    
                    report('E','Wrong numbering')
                
            end
            
            
            app.FromEditField.Enable = true;
            app.FromEditField.Limits = lims;
            app.FromEditField.Value = from;
            app.ToEditField.Enable = true;
            app.ToEditField.Limits = lims;
            app.ToEditField.Value = to;
            
        end
        
        function setG(app,G,refresh)
            
            if nargin<3
                refresh=false;
            end
            
            app.G = G;
            
            %if ~refresh
                app.ti = G.ti;
                app.tf = min([app.G.tf,app.ti+1999]);
                update_frame_numbers(app);
            %end
            
            app.GPlotButton.Enable = true;
            app.FromEditField.Enable = true;
            app.ToEditField.Enable = true;
                        
            app.MovieDropDown.Value = num2str(G.movlist);
            
            setXY(app);
            report('I','Ready')
        end
        
        function setXY(app)
            
            if exist(app.G.xyfile,'file')
                app.XY = load(app.G.xyfile);
            else
                app.XY = [];
                report('W','No XY file found')
            end
            
            
        end
        
        
        function plot_G(app)


            d = uiprogressdlg(app.CATTGraphExplorerUIFigure,'Title','Plotting data','Indeterminate','on');
        
            fi = app.ti.f;
            ff = app.tf.f;
            
            
            % extract subgraph to plot
            if ~isfield(app.G.aux,'fi')
                app.G.aux.fi = [app.G.trjs.fi];
                app.G.aux.ff = [app.G.trjs.ff];
            end
            node_fi = app.G.aux.fi;
            node_ff = app.G.aux.ff;
            
            include = find((node_ff >= fi) & (node_fi <=ff));
            exclude = indegree(app.G.G,include)==0 & outdegree(app.G.G,include)==0 & torow(~any(app.G.possible_ids(include,:),2));
            include = include(~exclude);
            
            if isempty(include)
                report('E','slice contained no nodes')
                return
            end
            
            app.sg = subgraph(app.G.G,include);
            try
                app.sg_assigned_ids = app.G.assigned_ids(include,:);
                app.sg_possible_ids = app.G.possible_ids(include,:);
            catch
                app.sg_assigned_ids = false(length(include),:);
                app.sg_possible_ids = false(length(include),:);
            end
            
            % find first and last layer tracklet
            app.sg_trjs = [app.sg.Nodes.trj];
            first = find([app.sg_trjs.fi]<=fi);
            last = find([app.sg_trjs.ff]>=ff);
            both = intersect(first,last);
            last = setdiff(last,both);
            
            z = app.sg.numnodes;
            
            nodey = tocol([app.sg_trjs.fi]);
            
            
            % create dummy nodes for singular nodes
            for i=1:length(both)
                app.sg = addnode(app.sg,['dummy',num2str(i)]);
                app.sg = addedge(app.sg,app.sg.Nodes(both(i),:).Name,['dummy',num2str(i)],1);
                nodey = [nodey;max(nodey)];
            end


            dummies = tocol(z+1:app.sg.numnodes);
            
            app.sg_src_nodes    = find(ismember({app.sg_trjs.propID},app.G.usedIDs));
            app.sg_single_nodes = find(tocol(app.sg_trjs.isSingle) & tocol(~ismember({app.sg_trjs.propID},app.Trck.labels.nonant_labels)));
            
            
            from = app.sg.Edges.EndNodes(:,1);
            to = app.sg.Edges.EndNodes(:,2);
            
            app.sg_ant_nodes  = find(~ismember({app.sg_trjs.propID},app.Trck.labels.nonant_labels));
            
            to_keep = ismember(from,app.sg.Nodes.Name(app.sg_ant_nodes)) & ismember(to,app.sg.Nodes.Name(app.sg_ant_nodes));
            
            app.sg_ant_subgraph = rmedge(app.sg,find(~to_keep));
                        
            % create the graph plot
            app.GraphPlot = plot(app.GAxes,app.sg,'Layout','layered','Sources',first,'Sinks',[tocol(last);dummies],'NodeLabel',[]);
            if app.YpositionbytimeCheckBox.Value
                nodex = get(app.GraphPlot,'XData');
                set(app.GraphPlot,'XData',nodex,'YData',-nodey)
            end
            set(app.GAxes,'XTick',[],'YTick',[],'Box','on')
            
            
            highlight_none(app);
            
            
            app.IDhiglightDropDown.Enable = true;
            app.ZoomButton.Enable = true;
            app.ViewResetButton.Enable = true;
            
            %labels = arrayfun(@(x) [num2str(x.index)]  ,[sg.Nodes.trj],'UniformOutput',false);
            %labelnode(h,1:size(sg.Nodes,1),labels);
            
            set(app.GraphPlot,'ButtonDownFcn',@app.pick_node)
            
            
            app.GAxes.XLimMode = 'auto';
            app.GAxes.YLimMode = 'auto';
            
            plot_xy(app);
            
            id = app.IDhiglightDropDown.Value;
            
            if ismember(id,app.G.usedIDs)
                highlight_id(app,id);
            end
            
            close(d)
            
        end
        
        function highlight_none(app)
            
            % all including noant
            highlight(app.GraphPlot,app.sg,'Marker','o','EdgeColor',[0.9,0.9,0.9],'NodeColor',[0.9,0.9,0.9],'LineWidth',0.5,'MarkerSize',2)
            
            % now without noant
            highlight(app.GraphPlot,app.sg_ant_subgraph,'Marker','s','EdgeColor',[0.7,0.7,0.7],'NodeColor',[0.7,0.7,0.7],'LineWidth',1,'MarkerSize',4)
            
            % now single ant nodes
            highlight(app.GraphPlot,app.sg_single_nodes,'Marker','o','EdgeColor',[0.7,0.7,0.7],'NodeColor',[0.7,0.7,0.7],'LineWidth',1,'MarkerSize',4)
            
            % now src nodes
            highlight(app.GraphPlot,app.sg_src_nodes,'Marker','o','EdgeColor',[0.3,0.3,0.3],'NodeColor',[0.3,0.3,0.3],'LineWidth',1,'MarkerSize',4)
            
           
        end
        
        function highlight_id(app,id)
            
            
            if ischar(id)
                id = find(strcmp(id,app.G.usedIDs));
            end
            highlight_none(app);
            if isempty(id)
                return
            end
            
            idstr = app.G.Trck.usedIDs{id};


            id_nodes = find(app.sg_possible_ids(:,id)|app.sg_assigned_ids(:,id));
            
            from = app.sg.Edges.EndNodes(:,1);
            to = app.sg.Edges.EndNodes(:,2);
            
            to_keep = ismember(from,app.sg.Nodes.Name(id_nodes)) & ismember(to,app.sg.Nodes.Name(id_nodes));
            
            idsg = rmedge(app.sg,find(~to_keep));
            
            highlight(app.GraphPlot,idsg,'EdgeColor','r','NodeColor','r','LineWidth',1,'MarkerSize',4);
            assigned_nodes =  find(app.sg_assigned_ids(:,id));
            highlight(app.GraphPlot,assigned_nodes,'NodeColor','m','MarkerSize',4);
            idsrcnodes = app.sg_src_nodes(strcmp(idstr,{app.sg_trjs(app.sg_src_nodes).autoID}));
            idsrcnodes = intersect(idsrcnodes,id_nodes);
            
            highlight(app.GraphPlot,idsrcnodes,'NodeColor','g','MarkerSize',4);
            
            plot_xy_id(app);
            
        end
        
        function pick_node(app,GP,event)
            
            % get node
            pos = event.IntersectionPoint;
            d = sqrt((GP.XData - pos(1)).^2 + (GP.YData - pos(2)).^2);
            
            ind = argmin(d);
            trj = app.sg_trjs(ind);
            Gind = findnode(app.G.G,trj.name);
            
            assigned_ids = app.G.usedIDs(app.sg_assigned_ids(ind,:));
            assigned_str = strjoin(assigned_ids,' ');
            possible_ids = app.G.usedIDs(app.sg_possible_ids(ind,:));
            possible_ids = setdiff(possible_ids,assigned_ids);
            possible_str = strjoin(possible_ids,' ');
            
            % highligh selected node
            highlight(app.GraphPlot,app.sg,'MarkerSize',4)
            highlight(app.GraphPlot,ind,'MarkerSize',8);
            
            % print tracklet info
            text{1} =     ['Name: ',trj.name];
            text{end+1} = ['Index in G: ',num2str(Gind)];
            text{end+1} = ['Length: ',num2str(trj.len)];
            text{end+1} = ['From: ',num2str(trj.fi),' (',num2str(trj.ti.mf),')'];
            text{end+1} = ['To:   ',num2str(trj.ff),' (',num2str(trj.tf.mf),')'];
            text{end+1} = ['manualID: ',trj.manualID];
            text{end+1} = ['autoID: ',trj.autoID];
            text{end+1} = ['Assigned: ',assigned_str];
            text{end+1} = ['Possible: ',possible_str];
            
            app.TrackletTextArea.Value = text;
            set_focus_trj(app,trj)


        end
        
        function set_focus_trj(app,trj)
            
            if nargin>1
                app.focus_trj = trj;
            else
                trj = app.focus_trj;
            end
            

            app.focus_node = findnode(app.G.G,trj.name);
            app.FrameDropDown.Items = arrayfun(@(x) [num2str(trj.time2indx(x)), '(',num2str(x.f),')'],trj.tt,'UniformOutput',false);
            app.FrameDropDown.Value = app.FrameDropDown.Items{1};
        
            
            app.ImageButton.Enable = true;
            app.ShowframeButton.Enable = true;
            app.FrameDropDown.Enable = true;
            
            app.AssignButton.Enable = false;
            
            app.IDDropDown.Enable = true;
            
            
            app.AssignButton.Enable = false;
            
            
            % set default id
            if ismember(app.focus_trj.manualID,app.Trck.allLabels)
                id = app.focus_trj.manualID;
            elseif nnz(app.G.assigned_ids(app.focus_node,:))==1
                id = app.G.usedIDs{app.G.assigned_ids(app.focus_node,:)};
            elseif ismember(app.focus_trj.autoID,app.Trck.allLabels)
                id = app.focus_trj.autoID;
            else
                id = app.IDDropDown.Value;
            end
            app.IDDropDown.Value = id;


            if trj.isSingle('criteria',{'maxarea'})
                app.AssignButton.Enable = true;
                app.ExportallButton.Enable = true;
                app.ChooseframesButton.Enable = true;
                app.ImageallframesButton.Enable = true;
                ImageButtonPushed(app)
            else
                app.AssignButton.Enable = true;
                app.ExportallButton.Enable = false;
                app.ChooseframesButton.Enable = false;
                app.ImageallframesButton.Enable = false;
                imshow(zeros(100,100,3),'Parent',app.ImageAxes)
                set(app.ImageAxes,'XTick',[],'YTick',[],'Box','on')
                
            end
            
            plot_xy_trj(app);
            
        end
    
    
        function plot_xy(app)
                        
            app.XYAxes.cla;
            app.XAxes.cla;
            app.YAxes.cla;
            
            hold(app.XYAxes,'on')
            hold(app.XAxes,'on')
            hold(app.YAxes,'on')
            
            m = app.ti.m;
            app.lines.mfrom = app.ti.mf;
            app.lines.mto = app.tf.mf;
            
            switch app.numbering
                case 'experiment'
                    app.lines.xdata = app.ti.f:app.tf.f;
                case 'subdir'
                    sd = m>=[app.Trck.er.subdirs.mi] & m<=[app.Trck.er.subdirs.mf];
                    sdfi = app.Trck.er.movies_info(app.Trck.er.subdirs(sd).mi).fi;
                    app.lines.xdata = [app.ti.f:app.tf.f] - sdfi + 1;
                case 'movie'
                    app.lines.xdata = app.ti.mf:app.tf.mf;
                otherwise  
                    report('E','Wrong numbering')
            end
            
            % plot all ids
            fn = fieldnames(app.XY);
            for i=1:length(fn)
                id = fn{i};
                app.lines.xy_all = plot(app.XYAxes,app.XY.(id)(app.lines.mfrom:app.lines.mto,1),app.XY.(id)(app.lines.mfrom:app.lines.mto,2),'Color',[0.7,0.7,0.7,0.5],'LineWidth',1);
                app.lines.x_all = plot(app.XAxes,app.lines.xdata,app.XY.(id)(app.lines.mfrom:app.lines.mto,1),'Color',[0.7,0.7,0.7,0.5],'LineWidth',1);
                app.lines.y_all = plot(app.YAxes,app.lines.xdata,app.XY.(id)(app.lines.mfrom:app.lines.mto,2),'Color',[0.7,0.7,0.7,0.5],'LineWidth',1);
            end

            set(app.XYAxes,'XTick',[],'YTick',[],'Box','on')
            set(app.XAxes,'XTick',[],'YTick',[],'Box','on')
            set(app.YAxes,'XTick',[],'YTick',[],'Box','on')
            
            plot_xy_id(app)
            
            
        end
    
        function plot_xy_id(app)


            fn = fieldnames(app.XY);
            id = app.IDhiglightDropDown.Value;
            
            % delete previous
            
            if isfield(app.lines,'xy_id')
                delete(app.lines.xy_id);
            end
            
            if isfield(app.lines,'x_id')
                delete(app.lines.x_id);
            end
            
            if isfield(app.lines,'y_id')
                delete(app.lines.y_id);
            end
            
            % plot current
            
            if ismember(id,fn)
                app.lines.xy_id = plot(app.XYAxes,app.XY.(id)(app.lines.mfrom:app.lines.mto,1),app.XY.(id)(app.lines.mfrom:app.lines.mto,2),'Color',[0.9,0.7,0.7],'LineWidth',1);
                app.lines.x_id = plot(app.XAxes,app.lines.xdata,app.XY.(id)(app.lines.mfrom:app.lines.mto,1),'Color',[0.9,0.7,0.7],'LineWidth',1);
                app.lines.y_id = plot(app.YAxes,app.lines.xdata,app.XY.(id)(app.lines.mfrom:app.lines.mto,2),'Color',[0.9,0.7,0.7],'LineWidth',1);
            end
        
        
        end
    
        function plot_xy_trj(app)


            focus_id = app.IDhiglightDropDown.Value;
            focus_id = strcmp(focus_id,app.G.usedIDs);
            is_in_focus = app.G.assigned_ids(app.focus_node,focus_id);
            
            
            m = app.ti.m;
            
            switch app.numbering
                case 'experiment'
                    xdata1 = app.focus_trj.fi:app.focus_trj.ff;
                case 'subdir'
                    sd = m>=[app.Trck.er.subdirs.mi] & m<=[app.Trck.er.subdirs.mf];
                    sdfi = app.Trck.er.movies_info(app.Trck.er.subdirs(sd).mi).fi;
                    xdata1 = [app.focus_trj.fi:app.focus_trj.ff] - sdfi + 1;
                case 'movie'
                    xdata1 = app.focus_trj.ti.mf:app.focus_trj.tf.mf;
                otherwise  
                    report('E','Wrong numbering')
            end
            
            % delete previous
            
            if isfield(app.lines,'xy_trj')
                delete(app.lines.xy_trj);
            end
            
            if isfield(app.lines,'x_trj')
                delete(app.lines.x_trj);
            end
            
            if isfield(app.lines,'y_trj')
                delete(app.lines.y_trj);
            end
            
            % plot current
            if is_in_focus
                color = [0.9,0.4,0.4];
            else
                color = [0.2,0.2,0.2];
            end
            
            app.lines.xy_trj = plot(app.XYAxes,app.focus_trj.x,app.focus_trj.y,'Color',color,'LineWidth',3);
            app.lines.x_trj = plot(app.XAxes,xdata1,app.focus_trj.x,'Color',color,'LineWidth',3);
            app.lines.y_trj = plot(app.YAxes,xdata1,app.focus_trj.y,'Color',color,'LineWidth',3);
            
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Trck)
            set(app.GAxes,'XTick',[],'YTick',[],'Box','on')
            app.GAxes.XLabel.String='';
            app.GAxes.YLabel.String='';
            app.numbering = app.FramenumberingButtonGroup.SelectedObject.Text;


            if nargin>1
                if isa(Trck,'trhandles')
                    setTrck(app,Trck);
                elseif isa(Trck,'trgraph')
                    setTrck(app,Trck.Trck);
                    setG(app,Trck);
                else
                    report('E','Wrong arg to app')
                    return
                end
            end
        end

        % Callback function
        function ColonyDropDownValueChanged(app, event)
            colony = app.ColonyDropDown.Value;
            update_graph_list(app);
        end

        % Value changed function: ColonyDropDown, MovieDropDown
        function LoadButtonPushed(app, event)
            mstr = app.MovieDropDown.Value;
            m = str2num(mstr);
            c = app.ColonyDropDown.Value;
            load_G(app,m,c);
            app.LoadButton.Enable = true;
            
        end

        % Button pushed function: GPlotButton
        function GPlotButtonPushed(app, event)

            plot_G(app)
        end

        % Value changed function: IDhiglightDropDown
        function IDhiglightDropDownValueChanged(app, event)
            id = app.IDhiglightDropDown.Value;
            highlight_id(app,id);
        end

        % Button pushed function: ImageButton
        function ImageButtonPushed(app, event)
            %fh = tagged_figure('trj_image');
            %figure(fh);
            framestr = app.FrameDropDown.Value;
            s = strsplit(framestr,'(');
            ix = str2num(s{1});
            t = app.focus_trj.tt(ix);
            im=get_image(app.focus_trj,t);
            imshow(im,'Parent',app.ImageAxes)
            set(app.ImageAxes,'XTick',[],'YTick',[],'Box','on')
        end

        % Button pushed function: ImageallframesButton
        function ImageallframesButtonPushed(app, event)
            fh = tagged_figure('tracklet image');
            figure(fh);
            image(app.focus_trj,'all',[],[],false);
        end

        % Callback function
        function PlotXYButtonPushed(app, event)
            plot_xy_trj(app)
            
        end

        % Button pushed function: ExportallButton
        function ExportallButtonPushed(app, event)
            
            id = app.IDDropDown.Value;
            
            if app.focus_trj.isSingle('criteria',{'maxarea'})
                app.focus_trj.add_examples(id);
            else
                report('W','Trying to export examples from non single tracklet')
            end
            
        end

        % Button pushed function: AssignButton
        function AssignButtonPushed2(app, event)
            
            
            id = app.IDDropDown.Value;
            
            %if app.focus_trj.isSingle('criteria',{'maxarea'})
            app.focus_trj.add_manual_id(id);
            add_to_manual_cfg(app.Trck,app.focus_trj.name,'assign',id);
            app.set_focus_trj(app.focus_trj);
            
            %else
            %    report('W','Trying to assign a non single tracklet')
            %end
            
        end

        % Callback function
        function AssignButtonPushed(app, event)
            
            report('W','not implemented')
            return


        end

        % Callback function
        function ZoomButtonPushed(app, event)
            pan(app.GAxes,'on');
            zoom(app.GAxes,'on');
        end

        % Value changed function: ZoomButton
        function ZoomButtonValueChanged(app, event)
            value = app.ZoomButton.Value;
            if value
                pan(app.GAxes,'on');
                zoom(app.GAxes,'on');
            else
                pan(app.GAxes,'off');
                zoom(app.GAxes,'off');
            end
        end

        % Button pushed function: ViewResetButton
        function ViewResetButtonPushed(app, event)
            app.GAxes.XLimMode = 'auto';
            app.GAxes.YLimMode = 'auto';
        end

        % Button pushed function: ChooseframesButton
        function ChooseframesButtonPushed(app, event)
            export_frame_selector_app(app.focus_trj,app.IDDropDown.Value);
        end

        % Selection changed function: FramenumberingButtonGroup
        function FramenumberingButtonGroupSelectionChanged(app, event)
            app.numbering = app.FramenumberingButtonGroup.SelectedObject.Text;
            update_frame_numbers(app);
        end

        % Value changed function: FromEditField, ToEditField
        function update_ti_tf(app, event)
            fi = app.FromEditField.Value;
            ff = app.ToEditField.Value;
            m = app.G.movlist(1);
            
            switch app.numbering
                case 'experiment'
                    app.ti = trtime(app.Trck,fi);
                    app.tf = trtime(app.Trck,ff);
                case 'subdir'
                    sd = m>=[app.Trck.er.subdirs.mi] & m<=[app.Trck.er.subdirs.mf];
                    sdfi = app.Trck.er.movies_info(app.Trck.er.subdirs(sd).mi).fi;
                    app.ti = trtime(app.Trck,fi+sdfi-1);
                    app.tf = trtime(app.Trck,ff+sdfi-1);
                case 'movie'
                    app.ti = trtime(app.Trck,m,fi);
                    app.tf = trtime(app.Trck,m,ff);
                otherwise
                    report('E','Wring numbering')
            end
            
        end

        % Value changed function: YpositionbytimeCheckBox
        function YpositionbytimeCheckBoxValueChanged(app, event)
            plot_G(app)
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed2(app, event)
            mstr = app.MovieDropDown.Value;
            m = str2num(mstr);
            c = app.ColonyDropDown.Value;
            
            load_G(app,m,c,true);
            %plot_G(app);
            %if ~isempty(app.focus_trj)
            %    set_focus_trj(app)
            %end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CATTGraphExplorerUIFigure = uifigure('Visible', 'off');
            app.CATTGraphExplorerUIFigure.Name = 'CATT: Graph Explorer';
            app.GAxes = uiaxes(app.CATTGraphExplorerUIFigure);
            title(app.GAxes, '')
            xlabel(app.GAxes, 'X')
            ylabel(app.GAxes, 'Y')
            app.ColonyDropDownLabel = uilabel(app.CATTGraphExplorerUIFigure);
            app.ColonyDropDownLabel.Text = 'Colony:';
            app.ColonyDropDown = uidropdown(app.CATTGraphExplorerUIFigure);
            app.ColonyDropDown.ValueChangedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.ColonyDropDown.Value = 'Option 2';
            app.MovieDropDownLabel = uilabel(app.CATTGraphExplorerUIFigure);
            app.MovieDropDownLabel.Text = 'Movie:';
            app.MovieDropDown = uidropdown(app.CATTGraphExplorerUIFigure);
            app.MovieDropDown.ValueChangedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.ImageButton = uibutton(app.CATTGraphExplorerUIFigure, 'push');
            app.ImageButton.ButtonPushedFcn = createCallbackFcn(app, @ImageButtonPushed, true);
            app.ImageButton.Enable = 'off';
            app.ImageButton.Text = 'Image';
            app.ImageallframesButton = uibutton(app.CATTGraphExplorerUIFigure, 'push');
            app.ImageallframesButton.ButtonPushedFcn = createCallbackFcn(app, @ImageallframesButtonPushed, true);
            app.ImageallframesButton.Enable = 'off';
            app.ImageallframesButton.Text = 'Image all frames';
            app.FromLabel = uilabel(app.CATTGraphExplorerUIFigure);
            app.FromLabel.Text = 'From:';
            app.FromEditField = uieditfield(app.CATTGraphExplorerUIFigure, 'numeric');
            app.FromEditField.ValueChangedFcn = createCallbackFcn(app, @update_ti_tf, true);
            app.FromEditField.Enable = 'off';
            app.FromEditField.Value = 1;
            app.ToEditFieldLabel = uilabel(app.CATTGraphExplorerUIFigure);
            app.ToEditFieldLabel.Text = 'To:';
            app.ToEditField = uieditfield(app.CATTGraphExplorerUIFigure, 'numeric');
            app.ToEditField.ValueChangedFcn = createCallbackFcn(app, @update_ti_tf, true);
            app.ToEditField.Enable = 'off';
            app.ToEditField.Value = 1000;
            app.GPlotButton = uibutton(app.CATTGraphExplorerUIFigure, 'push');
            app.GPlotButton.ButtonPushedFcn = createCallbackFcn(app, @GPlotButtonPushed, true);
            app.GPlotButton.Enable = 'off';
            app.GPlotButton.Text = 'GPlot';
            app.FrameDropDownLabel = uilabel(app.CATTGraphExplorerUIFigure);
            app.FrameDropDownLabel.Text = 'Frame:';
            app.FrameDropDown = uidropdown(app.CATTGraphExplorerUIFigure);
            app.FrameDropDown.Enable = 'off';
            app.IDhiglightDropDownLabel = uilabel(app.CATTGraphExplorerUIFigure);
            app.IDhiglightDropDownLabel.Text = 'ID higlight:';
            app.IDhiglightDropDown = uidropdown(app.CATTGraphExplorerUIFigure);
            app.IDhiglightDropDown.ValueChangedFcn = createCallbackFcn(app, @IDhiglightDropDownValueChanged, true);
            app.IDhiglightDropDown.Enable = 'off';
            app.ExportallButton = uibutton(app.CATTGraphExplorerUIFigure, 'push');
            app.ExportallButton.ButtonPushedFcn = createCallbackFcn(app, @ExportallButtonPushed, true);
            app.ExportallButton.Enable = 'off';
            app.ExportallButton.Text = 'Export all';
            app.IDLabel = uilabel(app.CATTGraphExplorerUIFigure);
            app.IDLabel.Text = 'ID:';
            app.IDDropDown = uidropdown(app.CATTGraphExplorerUIFigure);
            app.IDDropDown.Enable = 'off';
            app.TrackletTextArea = uitextarea(app.CATTGraphExplorerUIFigure);
            app.ShowframeButton = uibutton(app.CATTGraphExplorerUIFigure, 'push');
            app.ShowframeButton.Enable = 'off';
            app.ShowframeButton.Text = 'Show frame';
            app.AssignButton = uibutton(app.CATTGraphExplorerUIFigure, 'push');
            app.AssignButton.ButtonPushedFcn = createCallbackFcn(app, @AssignButtonPushed2, true);
            app.AssignButton.Enable = 'off';
            app.AssignButton.Text = 'Assign';
            app.ZoomButton = uibutton(app.CATTGraphExplorerUIFigure, 'state');
            app.ZoomButton.ValueChangedFcn = createCallbackFcn(app, @ZoomButtonValueChanged, true);
            app.ZoomButton.Enable = 'off';
            app.ZoomButton.Text = 'Zoom';
            app.ViewResetButton = uibutton(app.CATTGraphExplorerUIFigure, 'push');
            app.ViewResetButton.ButtonPushedFcn = createCallbackFcn(app, @ViewResetButtonPushed, true);
            app.ViewResetButton.Enable = 'off';
            app.ViewResetButton.Text = 'View Reset';
            app.ChooseframesButton = uibutton(app.CATTGraphExplorerUIFigure, 'push');
            app.ChooseframesButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseframesButtonPushed, true);
            app.ChooseframesButton.Enable = 'off';
            app.ChooseframesButton.Text = 'Choose frames';
            app.FramenumberingButtonGroup = uibuttongroup(app.CATTGraphExplorerUIFigure);
            app.FramenumberingButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FramenumberingButtonGroupSelectionChanged, true);
            app.FramenumberingButtonGroup.Title = 'Frame numbering';
            app.experimentButton = uiradiobutton(app.FramenumberingButtonGroup);
            app.experimentButton.Text = 'experiment';
            app.experimentButton.Value = true;
            app.subdirButton = uiradiobutton(app.FramenumberingButtonGroup);
            app.subdirButton.Text = 'subdir';
            app.movieButton = uiradiobutton(app.FramenumberingButtonGroup);
            app.movieButton.Text = 'movie';
            app.ImageAxes = uiaxes(app.CATTGraphExplorerUIFigure);
            app.XYAxes = uiaxes(app.CATTGraphExplorerUIFigure);
            app.XAxes = uiaxes(app.CATTGraphExplorerUIFigure);
            app.YAxes = uiaxes(app.CATTGraphExplorerUIFigure);
            app.YpositionbytimeCheckBox = uicheckbox(app.CATTGraphExplorerUIFigure);
            app.YpositionbytimeCheckBox.ValueChangedFcn = createCallbackFcn(app, @YpositionbytimeCheckBoxValueChanged, true);
            app.YpositionbytimeCheckBox.Text = 'Y position by time';
            app.YpositionbytimeCheckBox.Value = true;
            app.LoadButton = uibutton(app.CATTGraphExplorerUIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed2, true);
            app.LoadButton.Text = 'Load';
            app.SingleantCheckBox = uicheckbox(app.CATTGraphExplorerUIFigure);
            app.SingleantCheckBox.Enable = 'off';
            app.SingleantCheckBox.Text = 'Single ant';
            app.EliminateButton = uibutton(app.CATTGraphExplorerUIFigure, 'push');
            app.EliminateButton.Enable = 'off';
            app.EliminateButton.Text = 'Eliminate';
            app.ManualfixLabel = uilabel(app.CATTGraphExplorerUIFigure);
            app.ManualfixLabel.Text = 'Manual fix:';
            app.AddexamplesLabel = uilabel(app.CATTGraphExplorerUIFigure);
            app.AddexamplesLabel.Text = 'Add examples:';
            % Show the figure after all components are created
            app.CATTGraphExplorerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = graph_explorer_app(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CATTGraphExplorerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CATTGraphExplorerUIFigure)
        end
    end
end